classdef PrimeNumbersPracticeTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PrimesAboveEditField      matlab.ui.control.NumericEditField
        GeneratenumbersfromLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Answer                    matlab.ui.control.Label
        IsPrimeLabel              matlab.ui.control.Label
        NumberToEvalLabel         matlab.ui.control.Label
        GenerateButton            matlab.ui.control.Button
        AnswerLabel               matlab.ui.control.Label
        YesButton                 matlab.ui.control.Button
        NoButton                  matlab.ui.control.Button
        TitleLabel                matlab.ui.control.Label
        PrimesBelowEditField      matlab.ui.control.NumericEditField
        toLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        PrimesList % Int vector with primes below certain number
        SelectedNumber % Prime to be evaluated
        IsPrime % Bool to check if selected number is prime
    end
    
    methods (Access = private)
        
        function generateAndDrawRandomNumber(app)
            if (rand() > 0.5)
                number = randi([app.PrimesAboveEditField.Value, ...
                    app.PrimesBelowEditField.Value]);
            else
                idx = randsample(numel(app.PrimesList),1);
                number = app.PrimesList(idx);
            end
            app.SelectedNumber = number;
            app.NumberToEvalLabel.Text = int2str(app.SelectedNumber);
            drawnow
        end

        function isSelectedNumberPrime(app)
            app.IsPrime = (sum(app.PrimesList == app.SelectedNumber) > 0);
        end
        
        function evalAnswer(app,evalFlag)
            app.Answer.Text = "";
            pause(0.1)

            isCorrect = ~xor(evalFlag,app.IsPrime);
            if isCorrect
                app.Answer.Text = "Correct";
                app.Answer.FontColor = [0 0.8 0];
            else
                app.Answer.Text = "Incorrect";
                app.Answer.FontColor = [0.8 0 0];
            end
            drawnow
        end
        
        function generatePrimesList(app)
            app.PrimesList = primes(app.PrimesBelowEditField.Value);
            app.PrimesList(app.PrimesList < ...
                app.PrimesAboveEditField.Value) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.generatePrimesList();
            app.generateAndDrawRandomNumber();
            app.isSelectedNumberPrime();
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.generateAndDrawRandomNumber();
            isSelectedNumberPrime(app)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.evalAnswer(true);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.evalAnswer(false);
        end

        % Value changed function: PrimesBelowEditField
        function PrimesBelowEditFieldValueChanged(app, event)
            app.generatePrimesList();
            app.generateAndDrawRandomNumber();
            app.isSelectedNumberPrime();
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case "space"
                    app.generateAndDrawRandomNumber();
                    isSelectedNumberPrime(app)
                case "y"
                    app.evalAnswer(true);
                case "n"
                    app.evalAnswer(false);
            end
        end

        % Value changed function: PrimesAboveEditField
        function PrimesAboveEditFieldValueChanged(app, event)
            app.generatePrimesList();
            app.generateAndDrawRandomNumber();
            app.isSelectedNumberPrime();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.PrimesBelowEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimesBelowEditField.Limits = [1 1000];
            app.PrimesBelowEditField.RoundFractionalValues = 'on';
            app.PrimesBelowEditField.ValueDisplayFormat = '%.0f';
            app.PrimesBelowEditField.ValueChangedFcn = createCallbackFcn(app, @PrimesBelowEditFieldValueChanged, true);
            app.PrimesBelowEditField.Value = 100;
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Prime Number Practice';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.AnswerLabel = uilabel(app.UIFigure);
            app.AnswerLabel.Text = 'Answer:';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate number';
            app.NumberToEvalLabel = uilabel(app.UIFigure);
            app.NumberToEvalLabel.Text = '100';
            app.IsPrimeLabel = uilabel(app.UIFigure);
            app.IsPrimeLabel.Text = 'Is this a prime number?';
            app.Answer = uilabel(app.UIFigure);
            app.Answer.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Press the spacebar to generate a new number. Then press the "y" key if the number is prime or the "n" key if it is not prime.';
            app.GeneratenumbersfromLabel = uilabel(app.UIFigure);
            app.GeneratenumbersfromLabel.Text = 'Generate numbers from';
            app.PrimesAboveEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimesAboveEditField.Limits = [1 1000];
            app.PrimesAboveEditField.RoundFractionalValues = 'on';
            app.PrimesAboveEditField.ValueDisplayFormat = '%.0f';
            app.PrimesAboveEditField.ValueChangedFcn = createCallbackFcn(app, @PrimesAboveEditFieldValueChanged, true);
            app.PrimesAboveEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrimeNumbersPracticeTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end